classdef MATLABJokeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TitleLabel              matlab.ui.control.Label
        CreateAuthorLabel       matlab.ui.control.Label
        GameSelectListBoxLabel  matlab.ui.control.Label
        GameSelectListBox       matlab.ui.control.ListBox
        StartButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.GameSelectListBox.Enable = 'off';
            app.StartButton.Enable = 'off';
            
            switch app.GameSelectListBox.Value
                case 'MineSweeper'
                    uiwait(msgbox('MineSweeper Start!','MineSweeper'));
                    MsRun();
                case 'Othello'
                case 'Tetris'
                    uiwait(msgbox('Tetris Start!','Tetris'));
                    TetrisRun();
            end
            
            app.GameSelectListBox.Enable = 'on';
            app.StartButton.Enable = 'on';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'MATLAB Joke App';
            app.CreateAuthorLabel = uilabel(app.UIFigure);
            app.CreateAuthorLabel.Text = 'Create by t.sakagami';
            app.GameSelectListBoxLabel = uilabel(app.UIFigure);
            app.GameSelectListBoxLabel.Text = 'Game Select';
            app.GameSelectListBox = uilistbox(app.UIFigure);
            app.GameSelectListBox.Items = {'MineSweeper', 'Othello(empty)', 'Tetris'};
            app.GameSelectListBox.Value = 'Tetris';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLABJokeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end